classdef image_alignment_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ArrowkeysShiftimagerRotateCCWtRotateCWLabel  matlab.ui.control.Label
        ResettoinitialalignmentButton   matlab.ui.control.Button
        SavetransformtoworkspaceButton  matlab.ui.control.Button
        AutomatedImageAlignmentButton   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Image1_orig % Copy of original Image1
        Image2_orig % Copy of original Image2
        Image1_temp % Local copy of Image1 to be modified
        Image2_temp % Local copy of Image2 to be modified
        Image2_tform = eye(3); % Transform to align Image2 with Image1
        verticalshift = 0; %Initialize amount of vertical translation in image. Up is positive.
        horizontalshift = 0; %Initialize amount of horizontal translation in image. Right is positive
        rotation = 0; %Initialize amount of rotation in image. Positive is CW. Negative is CCW
        rigidbody_tform %Matlab affine 2d structure for image transform
    end

    properties (Access = public)
        alignment_tform %This is the final alignment tform that is passed back
    end

    methods (Access = private)

        function updatePlot(app)
            %Display new image alignment
            imshowpair(app.Image1_temp, app.Image2_temp,'Parent',app.UIAxes);
        end

        function transformMatrix = updateTransform(app)
            %Update transform matrix with new translation and rotation
            transformMatrix = eye(3);
            transformMatrix(3,1) = app.horizontalshift;
            transformMatrix(3,2) = -app.verticalshift;
            transformMatrix(1,1) = cosd(app.rotation);
            transformMatrix(2,2) = cosd(app.rotation);
            transformMatrix(1,2) = sind(app.rotation);
            transformMatrix(2,1) = -sind(app.rotation);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Image1, Image2, varargin)
            p = inputParser;
            addParameter(p, 'image1_session_run', []);
            addParameter(p, 'image2_session_run', []);
            parse(p, varargin{:});
            inputs = p.Results;

            app.Image1_orig = Image1;
            app.Image2_orig = Image2;
            app.Image1_temp = app.Image1_orig;
            app.Image2_temp = app.Image2_orig;
            app.alignment_tform = [];

            % Update title string
            if isempty(inputs.image1_session_run) || isempty(inputs.image2_session_run)
                % Do nothing
            else
                if size(inputs.image1_session_run, 1) == 1
                    image1_string = sprintf('S%dR%d', inputs.image1_session_run(1, 1), inputs.image1_session_run(1, 2));
                else
                    image1_string = '';
                    for sessionrun = 1:size(inputs.image1_session_run, 1)
                        image1_string = image1_string + sprintf('S%dR%d', inputs.image1_session_run(sessionrun, 1), inputs.image1_session_run(sessionrun, 2));
                    end
                end

                if size(inputs.image2_session_run, 1) == 1
                    image2_string = sprintf('S%dR%d', inputs.image2_session_run(1, 1), inputs.image2_session_run(1, 2));
                else
                    image2_string = '';
                    for sessionrun = 1:size(inputs.image2_session_run, 1)
                        image2_string = image2_string + sprintf('S%dR%d', inputs.image2_session_run(sessionrun, 1), inputs.image2_session_run(sessionrun, 2));
                    end
                end


                title_string_suffix = sprintf('\nimage1:%s and image2:%s', image1_string, image2_string);
                app.UIAxes.Title.String = strcat(app.UIAxes.Title.String, title_string_suffix);
            end

            
            updatePlot(app);
        end

        % Button pushed function: AutomatedImageAlignmentButton
        function AutomatedImageAlignment_ButtonPushed(app, event)
            % Rigid body alignment
            [app.rigidbody_tform, app.Image2_tform, app.verticalshift, app.horizontalshift, app.rotation, app.Image2_temp]...
                = rigid_body_alignment(app.Image1_orig, app.Image2_orig);
            updatePlot(app); %Update plot with new image alignment
        end

        % Button pushed function: SavetransformtoworkspaceButton
        function SaveTForm_ButtonPushed(app, event)
            app.alignment_tform = app.Image2_tform;
        end

        % Button pushed function: ResettoinitialalignmentButton
        function Reset_ButtonPushed(app, event)
            app.Image2_tform = eye(3); %Reset transform matrix to standard rigid body transform matrix
            app.verticalshift = 0;
            app.horizontalshift = 0;
            app.rotation = 0;
            app.Image2_temp = app.Image2_orig;
            updatePlot(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'uparrow' %Shift one pixel up
                    app.verticalshift = app.verticalshift+0.5; %Up is being defined as positive
                case 'downarrow' %Shift one pixel down
                    app.verticalshift = app.verticalshift-0.5;
                case 'leftarrow' %Shift one pixel to left
                    app.horizontalshift = app.horizontalshift-0.5;
                case 'rightarrow' %Shift one pixel to right
                    app.horizontalshift = app.horizontalshift+0.5;
                case 'r' %CCW rotation by 1 degree
                    app.rotation = app.rotation-0.1;
                case 't' %CW rotation
                    app.rotation = app.rotation+0.1;
            end
            app.Image2_tform = updateTransform(app); %Convert image rotation and shifts into transform matrix
            app.rigidbody_tform = affine2d(app.Image2_tform); %Convert transform matrix into proper MATLAB format
            app.Image2_temp = imwarp(app.Image2_orig, app.rigidbody_tform,'OutputView',imref2d(size(app.Image1_orig))); %Apply transform to warp image2  into new alignment
            updatePlot(app); %Update plot with new alignment

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Image Alignment')
            app.AutomatedImageAlignmentButton = uibutton(app.GridLayout, 'push');
            app.AutomatedImageAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @AutomatedImageAlignment_ButtonPushed, true);
            app.AutomatedImageAlignmentButton.Text = {'Automated'; 'Image Alignment'};
            app.SavetransformtoworkspaceButton = uibutton(app.GridLayout, 'push');
            app.SavetransformtoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTForm_ButtonPushed, true);
            app.SavetransformtoworkspaceButton.Text = {'Save transform'; ' to workspace'};
            app.ResettoinitialalignmentButton = uibutton(app.GridLayout, 'push');
            app.ResettoinitialalignmentButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_ButtonPushed, true);
            app.ResettoinitialalignmentButton.Text = {'Reset to'; ' initial alignment'};
            app.ArrowkeysShiftimagerRotateCCWtRotateCWLabel = uilabel(app.GridLayout);
            app.ArrowkeysShiftimagerRotateCCWtRotateCWLabel.Text = {'Arrow keys = Shift image'; ''; ''; 'r = Rotate CCW'; 't = Rotate CW'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_alignment_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end